classdef htpDirectImport < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        GotoGithubMenu                matlab.ui.container.Menu
        GotoBlogMenu                  matlab.ui.container.Menu
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        MATfilecontainseegDataClassobjectLabel  matlab.ui.control.Label
        CSVfilecontainslistingofeegidandfilenamesLabel  matlab.ui.control.Label
        CopyMATPathtoClipboardButton  matlab.ui.control.Button
        MATEditField                  matlab.ui.control.EditField
        MATEditFieldLabel             matlab.ui.control.Label
        CopyCSVPathtoClipboardButton  matlab.ui.control.Button
        CSVEditField                  matlab.ui.control.EditField
        CSVEditFieldLabel             matlab.ui.control.Label
        StudyTitleLabel               matlab.ui.control.Label
        CreatesubfoldersgroupsandcopyeegdatafilesintofoldersLabel  matlab.ui.control.Label
        ConfirmTypeButton             matlab.ui.control.Button
        SelectdatafiletypeDropDown    matlab.ui.control.DropDown
        SelectdatafiletypeLabel       matlab.ui.control.Label
        ConfirmNetButton              matlab.ui.control.Button
        SelectNetTypeDropDown         matlab.ui.control.DropDown
        SelectNetTypeLabel            matlab.ui.control.Label
        CreateFoldersButton           matlab.ui.control.Button
        CreatesubfolderstructureandopenRAWfolderLabel  matlab.ui.control.Label
        OpenButton                    matlab.ui.control.Button
        SelectNewDataFolderEditField  matlab.ui.control.EditField
        SelectNewDataFolderEditFieldLabel  matlab.ui.control.Label
        HTPCleanedDataDirectImportLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        di % Description
    end
    
    methods (Access = private)
        
        function results = toggleBkColor(app, element, color)
            element.BackgroundColor = color;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.di = htpDirectImportClass();

            % setup GUI
            app.StudyTitleLabel.Text = "";
            app.SelectNetTypeDropDown.Items = app.di.populateChanDropDown();
            app.SelectdatafiletypeDropDown.Items = app.di.populateSelectdatafiletypeDropDown();
            app.CSVEditField.Editable = false;
            app.CopyCSVPathtoClipboardButton.Enable = false;
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            try
                folder = uigetdir('Select Data Directory');
                app.SelectNewDataFolderEditField.Value = folder;
                app.toggleBkColor(app.SelectNewDataFolderEditFieldLabel, [0.39,0.83,0.07])
                parts = strsplit(folder, '/');
                study_title = parts{end};
                app.StudyTitleLabel.Text = ['Study Title: ' study_title];
                app.di.configHandler('basePath', {folder,study_title});
            catch
            end

        end

        % Button pushed function: CreateFoldersButton
        function CreateFoldersButtonPushed(app, event)
            try
            app.di.preprocess_direct;
            app.toggleBkColor(app.CreatesubfoldersgroupsandcopyeegdatafilesintofoldersLabel, [0.39,0.83,0.07]);
            app.CSVEditField.Value = app.di.am.htpcfg.csvfile;
            app.MATEditField.Value = app.di.am.htpcfg.matfile;
            app.CopyCSVPathtoClipboardButton.Enable = true;
            app.CopyMATPathtoClipboardButton.Enable = true;
            catch
            end


        end

        % Button pushed function: ConfirmNetButton
        function ConfirmNetButtonPushed(app, event)
            chanInfoValue = app.SelectNetTypeDropDown.Value;
            chanInfoIdx = find(strcmp(chanInfoValue, app.SelectNetTypeDropDown.Items));
            app.di.configHandler('chanInfo', chanInfoIdx);
            app.toggleBkColor(app.SelectNetTypeLabel, [0.39,0.83,0.07])

        end

        % Button pushed function: ConfirmTypeButton
        function ConfirmTypeButtonPushed(app, event)
            chanInfoValue = app.SelectdatafiletypeDropDown.Value;
            chanInfoIdx = find(strcmp(chanInfoValue, app.SelectdatafiletypeDropDown.Items));
            app.di.configHandler('filterInfo', chanInfoIdx);
            app.toggleBkColor(app.SelectdatafiletypeLabel, [0.39,0.83,0.07])
        end

        % Button pushed function: CopyCSVPathtoClipboardButton
        function CopyCSVPathtoClipboardButtonPushed(app, event)
            clipboard('copy', app.di.am.htpcfg.csvfile)
        end

        % Button pushed function: CopyMATPathtoClipboardButton
        function CopyMATPathtoClipboardButtonPushed(app, event)
            clipboard('copy', app.di.am.htpcfg.matfile)
        end

        % Menu selected function: GotoGithubMenu
        function GotoGithubMenuSelected(app, event)
            web('http://github.com/cincibrainlab')
        end

        % Menu selected function: GotoBlogMenu
        function GotoBlogMenuSelected(app, event)
            web('https://www.cincibrainlab.com/post/')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.GotoGithubMenu = uimenu(app.Menu);
            app.GotoGithubMenu.MenuSelectedFcn = createCallbackFcn(app, @GotoGithubMenuSelected, true);
            app.GotoGithubMenu.Text = 'Go to Github';
            app.GotoBlogMenu = uimenu(app.Menu);
            app.GotoBlogMenu.MenuSelectedFcn = createCallbackFcn(app, @GotoBlogMenuSelected, true);
            app.GotoBlogMenu.Text = 'Go to Blog';
            app.HTPCleanedDataDirectImportLabel = uilabel(app.UIFigure);
            app.HTPCleanedDataDirectImportLabel.Text = '     HTP - Cleaned Data Direct Import';
            app.SelectNewDataFolderEditFieldLabel = uilabel(app.UIFigure);
            app.SelectNewDataFolderEditFieldLabel.Text = '1. Select New Data Folder';
            app.SelectNewDataFolderEditField = uieditfield(app.UIFigure, 'text');
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.CreatesubfolderstructureandopenRAWfolderLabel = uilabel(app.UIFigure);
            app.CreatesubfolderstructureandopenRAWfolderLabel.Text = '4. Create subfolder structure and open RAW folder';
            app.CreateFoldersButton = uibutton(app.UIFigure, 'push');
            app.CreateFoldersButton.ButtonPushedFcn = createCallbackFcn(app, @CreateFoldersButtonPushed, true);
            app.CreateFoldersButton.Text = 'Create Folders';
            app.SelectNetTypeLabel = uilabel(app.UIFigure);
            app.SelectNetTypeLabel.Text = '2. Select Net Type ';
            app.SelectNetTypeDropDown = uidropdown(app.UIFigure);
            app.ConfirmNetButton = uibutton(app.UIFigure, 'push');
            app.ConfirmNetButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmNetButtonPushed, true);
            app.ConfirmNetButton.Text = 'Confirm Net';
            app.SelectdatafiletypeLabel = uilabel(app.UIFigure);
            app.SelectdatafiletypeLabel.Text = '3. Select data file type';
            app.SelectdatafiletypeDropDown = uidropdown(app.UIFigure);
            app.ConfirmTypeButton = uibutton(app.UIFigure, 'push');
            app.ConfirmTypeButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmTypeButtonPushed, true);
            app.ConfirmTypeButton.Text = 'Confirm Type';
            app.CreatesubfoldersgroupsandcopyeegdatafilesintofoldersLabel = uilabel(app.UIFigure);
            app.CreatesubfoldersgroupsandcopyeegdatafilesintofoldersLabel.Text = 'Results:';
            app.StudyTitleLabel = uilabel(app.UIFigure);
            app.StudyTitleLabel.Text = 'StudyTitle';
            app.CSVEditFieldLabel = uilabel(app.UIFigure);
            app.CSVEditFieldLabel.Text = 'CSV';
            app.CSVEditField = uieditfield(app.UIFigure, 'text');
            app.CopyCSVPathtoClipboardButton = uibutton(app.UIFigure, 'push');
            app.CopyCSVPathtoClipboardButton.ButtonPushedFcn = createCallbackFcn(app, @CopyCSVPathtoClipboardButtonPushed, true);
            app.CopyCSVPathtoClipboardButton.Text = 'Copy CSV Path to Clipboard';
            app.MATEditFieldLabel = uilabel(app.UIFigure);
            app.MATEditFieldLabel.Text = 'MAT';
            app.MATEditField = uieditfield(app.UIFigure, 'text');
            app.CopyMATPathtoClipboardButton = uibutton(app.UIFigure, 'push');
            app.CopyMATPathtoClipboardButton.ButtonPushedFcn = createCallbackFcn(app, @CopyMATPathtoClipboardButtonPushed, true);
            app.CopyMATPathtoClipboardButton.Text = 'Copy MAT Path to Clipboard';
            app.CSVfilecontainslistingofeegidandfilenamesLabel = uilabel(app.UIFigure);
            app.CSVfilecontainslistingofeegidandfilenamesLabel.Text = 'CSV file contains listing of eegid and filenames';
            app.MATfilecontainseegDataClassobjectLabel = uilabel(app.UIFigure);
            app.MATfilecontainseegDataClassobjectLabel.Text = 'MAT file contains eegDataClass object';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Interface to import cleaned data into a HTP compatable analysis directory structure';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Load files from RAW directory, create eegDataClass Object, resave files into POSTCOMP directory.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = htpDirectImport

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end