classdef htpPreprocessingGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        preprocessingAppUIFigure       matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        Menu_2                         matlab.ui.container.Menu
        MATLABMenu                     matlab.ui.container.Menu
        ExporthtpPreprocessingClassMenu  matlab.ui.container.Menu
        PresetsMenu                    matlab.ui.container.Menu
        SaveasListedPresetMenu         matlab.ui.container.Menu
        SubjectLevelAnalysisPanel      matlab.ui.container.Panel
        AvailableStagesDropDown        matlab.ui.control.DropDown
        AvailableStagesDropDownLabel   matlab.ui.control.Label
        QuickAnalysisDropDown          matlab.ui.control.DropDown
        QuickAnalysisDropDownLabel     matlab.ui.control.Label
        SubjectSelectionDropDown       matlab.ui.control.DropDown
        SubjectSelectionDropDownLabel  matlab.ui.control.Label
        GoButton                       matlab.ui.control.Button
        LoadCSVButton                  matlab.ui.control.Button
        CurrentCSVXXLabel              matlab.ui.control.Label
        ManualReviewButton             matlab.ui.control.Button
        Stage4AutoButton               matlab.ui.control.Button
        AutoStage13Button              matlab.ui.control.Button
        PrepareMRAButton               matlab.ui.control.Button
        LoadStagesButton               matlab.ui.control.Button
        PrepareFilesButton             matlab.ui.control.Button
        ViewFolderButton               matlab.ui.control.Button
        Step1SetupPresetsandFileLocationsLabel  matlab.ui.control.Label
        LastButton                     matlab.ui.control.Button
        Stage4Button                   matlab.ui.control.Button
        Stage3Button                   matlab.ui.control.Button
        Stage2Button                   matlab.ui.control.Button
        Stage1Button                   matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        OpenButton                     matlab.ui.control.Button
        OutputFolderEditField          matlab.ui.control.EditField
        OutputFolderEditFieldLabel     matlab.ui.control.Label
        FileFolderEditField            matlab.ui.control.EditField
        FileFolderEditFieldLabel       matlab.ui.control.Label
        NetTypeDropDown                matlab.ui.control.DropDown
        NetTypeLabel                   matlab.ui.control.Label
        StudyNameEditField             matlab.ui.control.EditField
        StudyNameEditFieldLabel        matlab.ui.control.Label
        UserInitialsEditField          matlab.ui.control.EditField
        UserInitialsEditFieldLabel     matlab.ui.control.Label
        PresetDropDown                 matlab.ui.control.DropDown
        PresetDropDownLabel            matlab.ui.control.Label
        OptionsPanel                   matlab.ui.container.Panel
        OutputTextArea                 matlab.ui.control.TextArea
        OutputTextAreaLabel            matlab.ui.control.Label
        htpPreprocessing2020v1Label    matlab.ui.control.Label
    end

    
    properties (Access = private)
        am; % htpPreprocess (htpBaseMaster class)
        
        OptionDropDown        matlab.ui.control.DropDown;
        OptionLabel                  matlab.ui.control.Label;
        
        localSettings;
        
        fileExtFilter; % Description
        stageselect; % Description
        mra % manual review app
    end
    
    methods (Access = private)
        
        
        function results = actionHandler(app, action)
            try
                switch action
                    case 'firstrun'
                        agp = @(x) addpath(genpath(x));
                        agp('classes');
                        basedir = fileparts(which('htpPreprocessingGui'));
                        cd(basedir);
                        
                        % create object
                        app.am = htpPreprocessMaster;
                        app.am.firstRun;
                        app.setupEventListeners;
                        app.setupSelectOptions;
                        
                        app.updateStatusText('init','init');
                        
                        app.localSettings = struct();
                        setAnalysisModeSwitch(app, 'Group');
                        app.UserInitialsEditField.Enable = 0;
                        app.StudyNameEditField.Enable = 0;
                        
                        
                    otherwise
                        uialert(app.preprocessingApp, 'action Handler', 'check action Handler');
                        
                end
            catch ME
                disp('Error in ActionHandler');
                rethrow( ME );
            end
            results = 1;
        end
        
        function ex_callback(src, event)
            % get subject exclude status (default 0 = not excluded)
            s = src.UserData;
            exstatus = s.get_exclude_switch;
            
            % get handle for button
            % comps = findobj('tag', 'exbutton');
            comps = src;
            % change button based on exstatus
            switch exstatus
                case 0
                    s.exclude_subject( 'yes' );
                    set(comps, 'String', 'Include Subject')
                    comps.BackgroundColor = [1 0 0];
                    list = obj.xml_exclude;
                    [indx,tf] = listdlg('ListString',list);
                    s.exclude_category = list(indx)';
                    str = [list(indx)'];
                case 1
                    s.exclude_subject('no');
                    set(comps, 'String', 'Exclude Subject')
                    comps.BackgroundColor = [0 1 0];
                    s.exclude_category = [];
                    str = 'Quality Sufficient';
            end
            
            b4  = findobj('tag', 'qualitylabel');
            b4.String  = str;
            
            
        end
        
        function results = setAnalysisModeSwitch(app, value)
            
            % app.AnalysisModeSwitch.Value = value;
            
            app.am.setAnalysisMode( value );
            
            analysisModeSetup(app, value);
            
        end
        function analysisModeSetup(app, value)
            buttonHandler(app, 'init');
            switch value
                case 'Single'
                    app.FileFolderEditFieldLabel.Text = 'Single File:';
                    app.FileFolderEditField.Value ='';
                    app.FileFolderEditFieldLabel.Visible = 1;
                    buttonHandler(app, 'singleMode');
                    
                case 'Group'
                    % app.FileFolderEditFieldLabel.Text =
                    app.FileFolderEditFieldLabel.Text = 'Batch Folder:';
                    app.FileFolderEditField.Value ='Group Mode: Output Folder same as Input File.';
                    app.OutputFolderEditField.Enable = 0;
                    app.OutputFolderEditField.Value = 'Batch Folder Mode';
                    app.FileFolderEditFieldLabel.Visible = 0;
                    buttonHandler(app, 'groupMode');
            end
        end
        
        function buttonHandler( app, action )
            
            % main button handler for UI groups
            % define groups of buttons
            green = [0 1 0];
            red = [1 0 0];
            blue = [0.3 0.75 0.93];
            
            % mra options
            ui_mra = {app.ManualReviewButton, app.PrepareMRAButton};
            ui_mra_manual = {app.ManualReviewButton};
            
            % net options
            ui_net = { app.NetTypeDropDown };
            
            % group mode off
            %  ui_groupoff = { app.FileFolderEditField, app.SelectButton };
            
            % file/folder
            ui_fileAndFolder = {app.FileFolderEditField, app.OpenButton }; %app.FileFolderEditField
            
            % result folder
            ui_resultFolder = {app.OutputFolderEditField, app.FileFolderEditField }; %, app.SelectButton}; % delete:  , app.LastButton_2};
            
            % stage buttons
            ui_stages = { app.Stage1Button, app.Stage2Button, app.Stage3Button, app.Stage4Button }; %, app.LoadCSVButton };
            
            % save options
            ui_save = { app.LastButton }; % delete: , app.LastButton_2};
            
            % preset
            ui_preset = {app.PresetDropDown};
            
            % output folder
            ui_output = { app.OutputFolderEditField, app.ViewFolderButton };
            
            % action buttons
            ui_action = {app.PrepareFilesButton, app.LoadStagesButton};
            % ui_action = {app.PrepareFilesButton, app.LoadStagesButton, app.AvailableStagesDropDown, app.LoadinEEGLABButton, app.SaveasListedPresetButton};
            
            if app.am.is_savefile_valid == 1
                app.buttonControl( ui_save, 'on');
            else
                app.buttonControl( ui_save, 'off');
            end
            
            switch action
                
                case 'init'
                    
                    ui_arr = [ui_resultFolder ui_output ui_action];
                    % ui_arr = [ui_fileAndFolder ui_resultFolder ui_output ui_action];
                    
                    app.buttonControl( ui_arr, 'off');
                    app.buttonControl( ui_preset, 'on');
                    app.buttonControl( ui_net, 'on');
                    app.buttonControl( ui_mra, 'off');
                    
                    
                    app.PrepareFilesButton.BackgroundColor = [1 0 0];
                    app.am.htpcfg.completed.PrepareStageButton = 0;
                    
                    app.LoadStagesButton.BackgroundColor = red;
                    
                    % define net types (form: {'Display Name', 'file ext *.raw'; 'Next Name', 'Next ext'})
                    netPairs = {app.am.getNetDisplayNames; app.am.getNetNames};
                    
                    app.setOptions_NetTypesDropDown(netPairs);
                    
                    app.am.msgout(sprintf('Using saved file: %s',app.am.htpcfg.saveSettingFile), 'step_complete');
                    
                    % app.AnalysisModeButton.Enable = 0;
                    % app.toggleDetails( 'off');
                    
                    %   app.SaveButton.Enable = 0;
                    
                case 'netNow'
                    mode = app.am.htpcfg.analysisMode;
                    
                    switch mode
                        case 'Single'
                            ui_arr = [ui_fileAndFolder ui_resultFolder];
                            app.FileFolderEditField.Value = '';
                            app.buttonControl( ui_arr, 'on');
                        case 'Group'
                            ui_arr = ui_fileAndFolder;
                            app.FileFolderEditField.Value = '';
                            app.buttonControl( ui_arr, 'on');
                    end
                    
                case 'netOff'
                    ui_arr = [ui_fileAndFolder ui_resultFolder ui_save ui_output ui_action];
                    app.buttonControl( ui_arr, 'off');
                case 'resetPrepareButton'
                    
                    app.PrepareFilesButton.BackgroundColor = [1 0 0];
                    % app.PrepareFilesButton.Position = [120 743 341 24];
                    % app.PrepareFilesButton.Text = 'Prepare for Stage 1';
                    app.am.htpcfg.completed.PrepareStageButton = 0;
                    
                case 'groupMode'
                    ui_arr = [ui_fileAndFolder ui_resultFolder ui_output ui_stages];
                    
                    app.buttonControl( ui_arr, 'off');
                    app.PrepareFilesButton.Text = 'Load File List';
                    
                    
                    %                     ui_arr = [ui_groupoff];
                    %                     app.buttonVisible( ui_arr, 'off' );
                    
                    if app.am.htpcfg.completed.NetTypeDropDown == 1
                        app.buttonHandler( 'netNow' );
                    else
                        %    ui_arr = ui_fileAndFolder;
                        %    app.FileFolderEditField.Value = 'Please select net type first.';
                        %    app.buttonControl( ui_arr, 'off');
                        
                    end
                case 'singleMode'
                    
                    ui_arr = [ui_groupoff ui_stages];
                    app.buttonVisible( ui_arr, 'on' );
                    app.PrepareFilesButton.Text = 'Copy Raw File';
                    
                    
                    if app.am.htpcfg.completed.NetTypeDropDown == 1
                        app.buttonHandler( 'netNow' );
                    else
                        ui_arr = [ui_fileAndFolder ui_resultFolder ui_resultFolder ui_output];
                        app.FileFolderEditField.Value = 'Please select net type first.';
                        app.buttonControl( ui_arr, 'off');
                        
                    end
                    
                case 'FileFolderSelected'
                    mode = app.am.processing_mode;
                    
                    switch mode
                        case 'Single'
                            
                            if app.am.htpcfg.completed.FilenameEditField
                            end
                            
                        case 'Group'
                            ui_arr =  [ui_action app.ViewFolderButton];
                            app.buttonControl( ui_arr, 'on');
                            
                    end
                    
                case 'LastSingleFileOrFolder'
                    
                    mode = app.am.processing_mode;
                    
                    switch mode
                        case 'Single'
                            if app.am.htpcfg.completed.FileFolderEditField == 1 && app.htpcfg.completed.TargetFolderEditField  == 1
                                app.buttonHandler('FilesPrepared');
                            end
                            
                        case 'Group'
                    end
                    
                case 'StagesOn'
                    
                    ui_arr = ui_stages;
                    app.buttonControl( ui_arr, 'on');
                    
                case 'mraOff'
                    ui_arr = ui_mra;
                    app.buttonControl( ui_arr, 'off');
                    
                case 'mraOn'
                    ui_arr = ui_mra;
                    app.buttonControl( ui_arr, 'on');
                    
                case 'mraManualOff'
                    ui_arr = ui_mra_manual;
                    app.buttonControl( ui_arr, 'off');
                    
                case 'mraManualOn'
                    ui_arr = ui_mra_manual;
                    app.buttonControl( ui_arr, 'on');
                    
                case 'FilesPrepared'
                    if app.am.htpcfg.completed.FilenameEditField == 1 && app.am.htpcfg.completed.TargetFolderEditField == 1
                        mode = app.am.htpcfg.analysisMode;
                        switch mode
                            case 'Single'
                                ui_arr = ui_action;
                                checkfile = fullfile(app.am.htpcfg.targetPath, app.am.htpcfg.rawsubfolder, app.am.htpcfg.rawfile );
                                %app.PrepareFilesButton.BackgroundColor = green;
                                app.PrepareFilesButton.Text = 'Recopy File';
                                %app.PrepareFilesButton.BackgroundColor = blue;
                                
                                app.LoadStagesButton.Enable = 1;
                                app.buttonControl( ui_arr, 'on');
                                app.am.htpcfg.completed.PrepareStageButton = 1;
                                app.buttonHandler( 'prepare_button_clicked_once');
                                
                                
                                app.buttonControl( ui_output, 'on' );
                                
                            case 'Group'
                                
                                ui_arr = ui_action;
                                app.buttonControl( ui_arr, 'on');
                                app.PrepareFilesButton.BackgroundColor = green;
                                app.am.htpcfg.completed.PrepareStageButton = 1;
                                app.buttonHandler( 'prepare_button_clicked_once');
                                app.LoadStagesButton.BackgroundColor = green;
                                app.LoadStagesButton.Enable = 1;
                                
                                app.buttonControl( ui_output, 'on' );
                                
                            case 'Turn_On_Comp_Tools'
                                
                                
                        end
                        
                    end
                    
                case 'prepare_button_clicked_once'
                    if app.am.htpcfg.completed.PrepareStageButton == 1
                        
                        app.PrepareFilesButton.Text = 'Reload/Recopy';
                        app.PrepareFilesButton.BackgroundColor = blue;
                        app.LoadStagesButton.BackgroundColor = green;
                        
                    end
                    
                case 'lastLoaded'
                    mode = app.am.htpcfg.analysisMode;
                    
                    switch mode
                        case 'Single'
                            ui_arr = [ui_fileAndFolder ui_resultFolder ui_action];
                            app.buttonControl( ui_arr, 'on');
                            app.PrepareFilesButton.BackgroundColor = green;
                            app.buttonControl( ui_output, 'on' );
                            
                        case 'Group'
                            ui_arr = [ui_fileAndFolder ui_action];
                            app.PrepareFilesButton.BackgroundColor = green;
                            
                            app.buttonControl( ui_arr, 'on');
                            app.buttonControl( ui_output, 'on' );
                            
                    end
                    
            end
            
            
        end
        
        %         function toggleDetails(app, toggle)
        %
        %             switch toggle
        %                 case 'off'
        %                     t = 0;
        %                 case 'on'
        %                     t = 1;
        %             end
        %
        %             sdetails = {app.basenameLabel, app.SDetailsListBox, app.SOutputTextArea};
        %
        %             for i = 1 : length(sdetails)
        %                 sdetails{i}.Enable = t;
        %             end
        %         end
        
        
        function buttonControl( app, ui_arr, control )
            
            switch control
                case 'off'
                    toggle = 0;
                case 'on'
                    toggle = 1;
            end
            
            for i = 1 : length( ui_arr )
                
                if toggle == 1
                    ui_arr{i}.Enable = 1;
                else
                    ui_arr{i}.Enable = 0;
                end
                
            end
            
        end
        
        
        function buttonVisible( app, ui_arr, control )
            
            switch control
                case 'off'
                    toggle = 0;
                case 'on'
                    toggle = 1;
            end
            
            for i = 1 : length( ui_arr )
                
                if toggle == 1
                    ui_arr{i}.Visible = 1;
                else
                    ui_arr{i}.Visible = 0;
                end
                
            end
            
        end
        
        function setOptions_NetTypesDropDown(app, netPairs)
            
            instructStr = {'Please select EEG net type:'};
            
            app.NetTypeDropDown.Items = [instructStr netPairs{1,:}];
            app.NetTypeDropDown.ItemsData = ['N/A' netPairs{1,:}];
            app.NetTypeDropDown.Value = app.NetTypeDropDown.ItemsData{1};
            
        end
        
        function setupEventListeners(app)
            addlistener( app.am, 'step_complete', @(src, e) app.eventHandler(src, e));
            addlistener( app.am, 'step_error', @(src, e) app.eventHandler(src, e));
            addlistener( app.am, 'step_warning', @(src, e) app.eventHandler(src, e));
            addlistener( app.am, 'step_msg', @(src, e) app.eventHandler(src, e));
        end
        
        function displayTableAtAnyStage( app )
            
            getFileListTable
            
        end
        
        function setupSelectOptions(app)
            
            % setup net options
            netPairs = {app.am.getNetDisplayNames; app.am.getNetNames};
            app.setOptions_NetTypesDropDown(netPairs);
            % setup presets
            app.setOptions_PresetDropDown;
            
            % setup option panel
            app.setupOptionPanel;
            
        end
        
        function populateSingleSubjectOptions( app )
            
            app.SubjectSelectionDropDown.Items = strcat(app.am.mra_getBaseNames', ' (', compose('%d', [1:length(app.am.mra_getBaseNames)])', ')');
            app.SubjectSelectionDropDown.ItemsData = 1 : length(app.am.mra_getBaseNames);
            app.QuickAnalysisDropDown.Items = app.am.getSingleSubjectAnalysisItems;
            
        end
        
        function setupOptionPanel( app )
            % [left bottom width height]
            
            % sets up selection grid of options
            opt = app.am.htpcfg.opt;
            
            optcfg.optNames = fields(opt);
            optcfg.optTotal = length(fields(opt));
            optcfg.optCol = 4;
            
            rows = floor( (optcfg.optTotal) / optcfg.optCol );
            optcfg.rows = rows + mod((optcfg.optTotal), optcfg.optCol);
            
            panelSize = app.OptionsPanel.Position;
            
            height_offset = panelSize(4)-50;
            
            colWidth = panelSize(3) / floor( optcfg.optCol ) - 20;
            
            colPos = 1;
            
            woff = ones( optcfg.optCol, 1 );
            
            for i = 1 : optcfg.optCol
                if i == 1
                    woff(1) = 30;
                else
                    woff( i ) = woff( i-1 ) + colWidth;
                end
            end
            
            hspacing = 30;
            labelwidth = 140;
            
            cnt = 1;
            
            for i = 1 : optcfg.rows
                
                for j = 1 :  optcfg.optCol
                    if cnt <= optcfg.optTotal
                        app.OptionLabel(cnt) = app.createOptionLabel( app.OptionsPanel, optcfg.optNames{cnt}, ...
                            [woff(j) height_offset labelwidth 20] );
                        app.OptionDropDown(cnt) = app.createOptionDropDown( app.OptionsPanel, ...
                            [woff(j) height_offset-20 labelwidth 20], opt.(optcfg.optNames{cnt}) );
                        cnt = cnt + 1;
                    end
                end
                
                height_offset = height_offset - 40;
            end
        end
        
        function newOptLabel = createOptionLabel( app, target, text, position )
            
            newOptLabel = uilabel( target ); % target location
            newOptLabel.Position = position; % position
            newOptLabel.Text = text;
            newOptLabel.HorizontalAlignment = 'left';
            
        end
        
        function newOptDropDown = createOptionDropDown( app, target, position, items )
            
            newOptDropDown = uidropdown( target ); % target location
            newOptDropDown.Position = position; % position
            newOptDropDown.Items = items;
            
        end
        
        
        function updateStatusText(app, str, varargin)
            % logs messages to display
            if nargin > 2, msgtype = varargin{1}; else, msgtype = 'msg'; end
            switch msgtype
                case 'init', prefix = '(Start)'; str =  sprintf('Data Reset and Program Initialized\n');
                case 'msg', prefix = '';
                case 'warning', prefix = '(Warning)';
                case 'error', prefix = '(Error)';
                case 'complete', prefix = '(Complete)';
                otherwise
                    prefix = '';
            end
            updatetxt = sprintf('%s %s: %s', app.am.showTimeStamp, prefix, str);
            app.OutputTextArea.Value = [updatetxt; app.OutputTextArea.Value];
        end
        
        function setOptions_PresetDropDown(app)
            
            % Preset box populated from xml file
            items = {app.am.xml_presets{:,1}};
            app.PresetDropDown.Items = items;
            itemIdx = strcmp(app.PresetDropDown.Value, items);
            
        end
        
        function results = setNet(app, netvalue)
            value = app.am.NetNameDisplay2NetName(netvalue);
            
            app.am.setElecNow( value );
            
            try
                if ~strcmp(value, 'N/A')
                    app.fileExtFilter = app.am.htpcfg.chanNow.net_filter;
                    buttonHandler(app, 'netNow');
                    app.am.htpcfg.completed.NetTypeDropDown = 1;
                else
                    buttonHandler(app, 'netOff');
                    app.am.htpcfg.completed.NetTypeDropDown = 0;
                end
                results = 1;
            catch
                
                app.am.msgout('Error in Channel Assignment (NetNow).', 'step_error');
                
            end
        end
        
        function results = getSubject(app, stage)
            
            % selecting objects by CSV
            app.am.getStageCSV(stage, app.am.htpcfg.basePath);
            
            csvfile = app.am.htpcfg.csvfile;
            matfile = app.am.htpcfg.matfile;
            pathdb =  app.am.htpcfg.pathdb;
            
            % Load subject objects
            load(matfile, 'sub');
            arrayfun(@(sub) sub.setCsv( csvfile ), sub, 'UniformOutput',false );
            arrayfun(@(sub) sub.setMat( matfile ), sub, 'UniformOutput',false );
            arrayfun(@(sub) sub.updatePaths( app.am.htpcfg.basePath ), sub, 'UniformOutput',false );
            results = sub;
            
            
        end
        
        
        function results = getOptionValues(app)
            
            opt = app.am.htpcfg.opt;
            fieldnames = fields(opt);
            
            for i = 1 : length( app.OptionDropDown )
                optnow{i} = app.OptionDropDown(i).Value;
                optIdx(i) = find(strcmp(optnow{i}, opt.(fieldnames{i})));
            end
            
            app.am.setOptNow( optIdx );
            
            %app.am.msgout( 'Selected Options;', 'step_msg' );
            %             for i = 1 : length( fieldnames )
            %
            %                 str = sprintf( '%s: %s (%d)', fieldnames{i},  app.am.htpcfg.optnow.(fieldnames{i}){1}, optIdx(i));
            %
            %                 app.am.msgout( str, 'step_msg' );
            %
            %             end
            
            try
                app.am.setOptNow( optIdx );
                
            catch
                
            end
            
            
            results = optIdx;
            
        end
        
        function results = eventHandler(app, src, e)
            
            eventname = e.EventName;
            msg = src.msg;
            
            switch eventname
                
                case 'step_error'
                    app.updateStatusText( msg, 'error' );
                    if src.htpcfg.noeeglabpath == 0
                        uialert(app.UIFigure,...
                            'Critical Error: Please Add EEGLAB Directory to MATLAB and restart HTP.',...
                            'Missing Path', 'CloseFcn', @(src,event)mycallback(app,src,event));
                    end
                case 'step_warning'
                    app.updateStatusText( msg, 'warning' );
                case 'step_complete'
                    app.updateStatusText( msg, 'complete' );
                case 'step_msg'
                    app.updateStatusText( msg );
                otherwise
                    uialert(app.preprocessingApp, 'eventHandler', 'eventHandler');
            end


        end
        
        function updateSubTable( app, action )
            
            switch action
                case 'firstrun'
                    tbl = app.am.getFilenameTable;
                    
                    app.UITable.Data = tbl;
                    app.UITable.ColumnName = app.UITable.ColumnName(1);
                    app.UITable.ColumnName = tbl.Properties.VariableNames;
                    
                    app.CurrentCSVXXLabel.Text = 'Current CSV: No CSV selected. First Run.';
                    
                otherwise
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                app.actionHandler( 'firstrun' );
                
            catch ME
                
                disp('Error in startupFcn');
                rethrow( ME );
                
            end
            
        end

        % Menu selected function: ExporthtpPreprocessingClassMenu
        function ExporthtpPreprocessingClassMenuSelected(app, event)
            app.am.msgout('Variable ''am'' exported to MATLAB workspace');
            assignin( 'base', 'am', app.am );
        end

        % Value changed function: NetTypeDropDown
        function NetTypeDropDownValueChanged(app, event)
            
            value = app.NetTypeDropDown.Value;
            app.setNet(value);
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            mode = app.am.getAnalysisMode;
            
            switch mode
                
                case 'Single'
                    try
                        [file,path] = uigetfile(app.fileExtFilter);
                        figure(app.preprocessingAppUIFigure);
                    catch
                        app.am.msgout('Error: No file selected.', 'step_warning');
                        buttonHandler(app,'init');
                    end
                    
                    
                    if file ~= 0
                        app.FileFolderEditField.Value = fullfile(path,file);
                        app.am.setSingleRawFile( app.FileFolderEditField.Value );
                        buttonHandler(app, 'resetPrepareButton');
                    else
                        app.am.msgout('Error: No file selected.', 'step_warning');
                        buttonHandler(app,'init');
                    end
                    %updateRawFile(app);


                case 'Group'
                    try
                        folder = uigetdir(pwd,'Select Data Directory');
                        
                    catch
                        app.am.msgout('Error: No directory selected.');
                    end
                    
                    app.FileFolderEditField.Value = folder;
                    app.OutputFolderEditField.Value = folder;
                    
                    app.am.setSingleRawFile( app.FileFolderEditField.Value );
                    
                    %updateOutputDir(app);
                    figure(app.preprocessingAppUIFigure);
                    buttonHandler(app, 'FilesPrepared');
                    
            end
            
        end

        % Button pushed function: PrepareFilesButton
        function PrepareFilesButtonPushed(app, event)
            try
                app.UserInitialsEditField.Enable = 1;
                app.StudyNameEditField.Enable = 1;
                
                app.UserInitialsEditField.Value = 'Default User';
                UserInitialsEditFieldValueChanged(app);
                
                app.StudyNameEditField.Value = 'Default Study Title';
                
                app.getOptionValues;
                
                StudyNameEditFieldValueChanged(app);
                
                if app.am.check_user == 0
                    app.am.msgout('No username found. Please enter username before continuing.', 'step_error');
                end
                
                if app.am.check_study_title == 0
                    app.am.msgout('No study title found. Please enter study title before continuing.', 'step_error');
                end
                
                
                try
                    app.am.start_EegLab_If_Needed;
                    app.am.msgout(sprintf('Attempting to start EEGLAB...success. Path: %s', app.am.htpcfg.eeglabpath));
                catch
                    app.am.msgout(sprintf('Attempting to start EEGLAB...Failed. Path: %s', app.am.htpcfg.eeglabpath));
                end
                
                
                mode = 'Group'; % app.AnalysisModeSwitch.Value;
                
                switch mode
                    case 'Single'
                        
                        % copy original file to data directory
                        filename = app.FileFolderEditField.Value;
                        [~, fileroot, ~] = fileparts(filename);
                        app.am.msgout(sprintf('Single File Mode: Files Prepared.'));
                        
                        
                        % make RAW directory
                        app.am.targetpath2basepath( app.StudyFolderEditField.Value, fileroot );
                        
                        % copy file
                        app.am.copyRawfile2Basepath;
                        
                        app.OutputFolderEditField.Value = app.am.htpcfg.basePath;
                        
                        
                        % switch button to reflect reset
                        % app.am.htpcfg.completed.PrepareStageButton = 1;
                        %  disableFileOpen(app, 'on');
                        app.am.import_filelist;
                        buttonHandler(app, 'FilesPrepared');
                        app.am.msgout(sprintf('Single File Mode: Files Prepared.'));
                        app.am.saveLastSettings;
                        
                        
                    case 'Group'
                        app.am.import_filelist;
                        buttonHandler(app, 'FilesPrepared');
                        app.am.msgout(sprintf('Group File Mode: Files Prepared.'));
                        app.OutputFolderEditField.Value = app.am.htpcfg.basePath;
                        
                        app.am.saveLastSettings;
                end
            catch
            end
            
        end

        % Value changed function: UserInitialsEditField
        function UserInitialsEditFieldValueChanged(app, event)
            value = app.UserInitialsEditField.Value;
            app.am.set_user(value);
            
        end

        % Value changed function: StudyNameEditField
        function StudyNameEditFieldValueChanged(app, event)
            
            value = app.StudyNameEditField.Value;
            app.am.set_study_title(value);
            
            app.am.grp_cfg_setGrpTitle(app.StudyNameEditField.Value );
            %app.CustomSTUDYTitleEditField.Value = app.am.grp_cfg_getGrpTitle();
            %   app.STUDYfileEditFieldValueChanged(app.am);
        end

        % Button pushed function: LastButton
        function LastButtonPushed(app, event)
            
            % app.startupHandler('init');
            
            app.buttonHandler('init');
            
            mode = 'Group'; % app.AnalysisModeSwitch.Value;
            
            try
                switch mode
                    case 'Single'
                        
                        % app.loadLocalSettings; legacy
                        
                        app.am.loadLastSettings;
                        app.NetTypeDropDown.Value = app.am.htpcfg.chanNow.net_name;
                        app.setNet( app.NetTypeDropDown.Value  );
                        app.FileFolderEditField.Value = app.am.htpcfg.rawfile;
                        app.StudyFolderEditField.Value = app.am.htpcfg.targetPath;
                        
                        
                        app.am.setSingleRawFile( app.FileFolderEditField.Value );
                        app.am.setBasePath( app.StudyFolderEditField.Value );
                        
                        %app.am.saveLastSettings;
                        
                        app.buttonHandler('lastLoaded');
                        % app.buttonHandler('FilesPrepared');
                        
                        
                        %updateOutputDir(app);
                        %updateRawFile(app);
                        
                    case 'Group'
                        app.am.loadLastSettings;
                        currentNetDisplay = app.am.NetNameName2NetDisplay( app.am.htpcfg.localSettings.group.net );
                        app.NetTypeDropDown.Value = currentNetDisplay;
                        app.setNet( app.NetTypeDropDown.Value  );
                        
                        
                        %updateRawFile(app);
                        
                        buttonHandler(app, 'groupMode');
                        
                        %app.am.setSingleRawFile( app.FilenameEditField.Value );
                        app.am.setBasePath( app.am.htpcfg.localSettings.group.basePath );
                        app.FileFolderEditField.Value = app.am.htpcfg.localSettings.group.basePath;
                        
                        
                        app.buttonHandler('lastLoaded');
                end
                
            catch
                app.updateStatusText('ERROR: Local Saved Settings');
            end
        end

        % Button pushed function: LoadStagesButton
        function LoadStagesButtonPushed(app, event)
            try
                % clean input
                %mode = app.AnalysisModeSwitch.Value;
                mode ='Group';
                
                app.getOptionValues;
                
                if isempty( app.am.sub )
                    app.am.sub = app.getSubject( 'all' );
                    app.am.check_stages( app.am.sub );
                else
                    app.am.check_stages( app.am.sub );
                end
                
                items = app.am.stage_info;
                
                idx = find(cell2mat(items(2,:)));
                stages = items(1,:);
                itemList = stages(idx);
                
                if isempty(itemList)
                    app.am.msgout('No stages available', 'step_warning');
                    app.AvailableStagesDropDown.Enable = 0;
                    %  app.LoadinEEGLABButton.Enable = 0;
                    
                else
                    app.AvailableStagesDropDown.Enable = 1;
                    % app.LoadinEEGLABButton.Enable = 1;
                    app.AvailableStagesDropDown.Items = itemList;
                    app.stageselect = app.AvailableStagesDropDown.Value;
                    %     app.loadSubTable( app.stageselect );
                    app.populateSingleSubjectOptions;
                end
                app.LoadStagesButton.BackgroundColor = [0.3 0.75 0.93];
                app.buttonHandler('StagesOn');
                app.buttonHandler('mraOn');
                app.buttonHandler('mraManualOff');
                
                app.updateSubTable('firstrun');
                
            catch
                app.am.msgout('No stages available','step_warning');
                app.buttonHandler('StagesOn');
            end
            
            figure(app.preprocessingAppUIFigure);
            
            % app.getOptionValues;
        end

        % Button pushed function: PrepareMRAButton
        function PrepareMRAButtonPushed(app, event)
            app.getOptionValues;
            
            app.am.preprocess_optional('spectSeriesPlot');
            
            app.buttonHandler('mraOn');
            
            %             try
            %
            %                 app.loadSubTable( app.AvailableStagesDropDown.Value );
            %                 app.SaveButton.Enable = 0;
            %                 figure(app.preprocessingAppUIFigure);
            %
            %             catch
            %                 app.am.msgout( 'Exited dialog without selection.');
            %                 figure(app.preprocessingAppUIFigure);
            %             end
            %
        end

        % Button pushed function: Stage1Button
        function Stage1ButtonPushed(app, event)
            app.NetTypeDropDownValueChanged;
            app.getOptionValues;
            app.am.htpcfg.autoprocflag = 0;
            app.am.assignConfig2Subjects;
            app.am.preprocess_stage1;
        end

        % Value changed function: PresetDropDown
        function PresetDropDownValueChanged(app, event)
            value = app.PresetDropDown.Value;
            app.am.msgout(sprintf('Preset Selected: %s\n', value), 'complete');
            
            if strcmp(value, 'No preset selected')
                app.setupSelectOptions;
                app.am.msgout( 'Stage Options reset to default.' , 'complete');
                
            else
                items = app.am.xml_presets(:,1);
                itemIdx = find(strcmp(value, items));
                tempset = app.am.xml_presets(itemIdx,:);
                
                startidx = find(strcmp('Begin Options', tempset)) + 1;
                masterset = fields(app.am.htpcfg.opt);
                
                idx = startidx;
                j = 1;
                for i = startidx : 2 : length( tempset )
                    
                    if ischar(tempset{i})
                        
                        resultarr{j,1} = tempset{i};
                        resultarr{j,2} = tempset{i+1};
                        j = j +1;
                    else
                    end
                    
                end
                
                
                master_change_idx = zeros(length(masterset),1);
                master_change_opt = cell(length(master_change_idx),3);
                
                for i = 1 : length(resultarr)
                    disp(resultarr(i,1));
                    tmpstr = split(masterset,'_');
                    tempidx = find(contains( tmpstr(:,2), resultarr{i,1}));
                    master_change_idx(tempidx) = true;
                    master_change_opt{tempidx, 1} = true;
                    master_change_opt{tempidx, 2} = resultarr{i,1};
                    master_change_opt{tempidx, 3} = resultarr{i,2};
                    tmpstr(tempidx,2)
                end
                
                optlabels = {app.OptionLabel(:).Text}';
                optlabels = split(optlabels,'_');
                app.OptionDropDown ( logical(master_change_idx) ).Value
                
                tempidx = find(master_change_idx);
                
                for i = 1 : length( tempidx )
                    
                    app.OptionDropDown ( tempidx(i) ).Value = master_change_opt{tempidx(i), 3};
                    app.am.msgout(sprintf('%d. Option %s: %s',i, master_change_opt{tempidx(i), 2}, master_change_opt{tempidx(i), 3}), 'complete');
                    
                end
                
            end
            
            
        end

        % Menu selected function: SaveasListedPresetMenu
        function SaveasListedPresetMenuSelected(app, event)
            try
                
                
                datetime.setDefaultFormats('default','MMddyy');
                
                prompt = {'Enter Preset Display Name:'};
                dlgtitle = 'Save Current Settings as Preset:';
                
                dims = [1 35];
                definput = {'Default'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                
                titlestr = char(answer);
                code = [titlestr(regexp(titlestr,'\w+')) '_' char(datetime)];
                
            catch
                
            end
            
            app.getOptionValues;
            titlestr = titlestr;
            code = code;
            try
                net = app.am.htpcfg.chanNow.net_name;
                
                f = split(fields(app.am.htpcfg.optnow),'_');
                full = fields(app.am.htpcfg.optnow);
                fprintf('\n********* CUT AND PASTE INTO %s TO ADD PRESET ***********\n\n', app.am.xmlfile_presets);
                fprintf('\n<Study>\n\t<Title>%s</Title>\n\t<Code>%s</Code>\n\t<Net>%s</Net>\n\t\t<Settings>\n', titlestr, code, net);
                
                for i = 1 : length( f )
                    tmpvalue = char(app.am.htpcfg.optnow.(full{i}));
                    fprintf('\t\t\t<%s>%s</%s>\n', f{i,2}, tmpvalue ,f{i,2});
                    
                end
                fprintf('\t\t</Settings>\n</Study>\n\n');
                fprintf('\n********* CUT AND PASTE INTO %s TO ADD PRESET ***********\n\n', app.am.xmlfile_presets);
            catch
                app.am.msgout('No net selected. Cannot Save.', 'msg_warning');
            end
            
        end

        % Button pushed function: Stage2Button
        function Stage2ButtonPushed(app, event)
            app.getOptionValues;
            app.am.htpcfg.autoprocflag = 0;
            %
            %             % assign proper epochs for events
            %             if strcmp(app.am.htpcfg.optnow.Stage2_EpochType, 'Event')
            %                 % additional option selection
            %             app.dialog_EventEpochs = optionsDialogApp( app );
            %             end
            
            if strcmp('Yes', app.am.htpcfg.optnow.Stage2_MergeFiles)
                
                app.am.msgout('Merge mode active.');
                
            end
            
            app.am.preprocess_stage2;
            
        end

        % Button pushed function: Stage3Button
        function Stage3ButtonPushed(app, event)
            app.getOptionValues;
            app.am.htpcfg.autoprocflag = 0;
            app.am.preprocess_stage3;
        end

        % Button pushed function: Stage4Button
        function Stage4ButtonPushed(app, event)
            app.getOptionValues;
            app.am.htpcfg.autoprocflag = 0;
            app.am.preprocess_stage4;
        end

        % Button pushed function: AutoStage13Button
        function AutoStage13ButtonPushed(app, event)
            app.getOptionValues;
            app.am.htpcfg.optnow.Stage2_CleanMode = 'FullAuto';
            app.am.htpcfg.autoprocflag = 0;
            
            app.am.assignConfig2Subjects;
            
            app.am.preprocess_stage1;
            
            app.am.preprocess_stage2;
            
            app.am.preprocess_stage3;
            
            % app.am.preprocess_stage4;
        end

        % Button pushed function: Stage4AutoButton
        function Stage4AutoButtonPushed(app, event)
            app.getOptionValues;
            
            app.am.htpcfg.autoprocflag = 0;
            
            app.am.preprocess_stage4;
            
        end

        % Button pushed function: ViewFolderButton
        function ViewFolderButtonPushed(app, event)
            winopen(app.am.basePath);
        end

        % Button pushed function: ManualReviewButton
        function ManualReviewButtonPushed(app, event)
           app.ManualReviewButton.Enable = 0;
           
           app.mra = manualReviewApp( app, app.am );
        end

        % Button pushed function: LoadCSVButton
        function LoadCSVButtonPushed(app, event)
            try
                app.am.loadSub( 'postcomps' );
                app.am.updateBasePaths( app.FileFolderEditField.Value  );
                app.CurrentCSVXXLabel.Text = sprintf('CSV File: %s', app.am.htpcfg.csvfile);
                app.CurrentCSVXXLabel.FontColor = 'blue';
                app.populateSingleSubjectOptions;
            catch
                app.am.msgout('Unable to Load CSV', 'msg_warning');
            end
            
        end

        % Value changed function: AvailableStagesDropDown
        function AvailableStagesDropDownValueChanged(app, event)
            value = app.AvailableStagesDropDown.Value;
            
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            
           % app.SubjectSelectionDropDown
            index = app.SubjectSelectionDropDown.Value;
            action = app.QuickAnalysisDropDown.Value;
            s = app.am.sub( index );
            s.loadDataset( app.AvailableStagesDropDown.Value )
            
            app.am.quickAnalysisHandler(action, index);
            s.unloadDataset;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.preprocessingAppUIFigure = uifigure('Visible', 'off');
            app.preprocessingAppUIFigure.Name = 'preprocessingApp';
            app.Menu = uimenu(app.preprocessingAppUIFigure);
            app.Menu.Text = 'Menu';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = 'Menu';
            app.MATLABMenu = uimenu(app.preprocessingAppUIFigure);
            app.MATLABMenu.Text = 'MATLAB';
            app.ExporthtpPreprocessingClassMenu = uimenu(app.MATLABMenu);
            app.ExporthtpPreprocessingClassMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporthtpPreprocessingClassMenuSelected, true);
            app.ExporthtpPreprocessingClassMenu.Text = 'Export htpPreprocessingClass';
            app.PresetsMenu = uimenu(app.preprocessingAppUIFigure);
            app.PresetsMenu.Text = 'Presets';
            app.SaveasListedPresetMenu = uimenu(app.PresetsMenu);
            app.SaveasListedPresetMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasListedPresetMenuSelected, true);
            app.SaveasListedPresetMenu.Text = 'Save as Listed Preset';
            app.htpPreprocessing2020v1Label = uilabel(app.preprocessingAppUIFigure);
            app.htpPreprocessing2020v1Label.Text = ' htpPreprocessing 2020v1';
            app.OutputTextAreaLabel = uilabel(app.preprocessingAppUIFigure);
            app.OutputTextAreaLabel.Text = 'Output:';
            app.OutputTextArea = uitextarea(app.preprocessingAppUIFigure);
            app.OptionsPanel = uipanel(app.preprocessingAppUIFigure);
            app.OptionsPanel.Title = 'Options';
            app.PresetDropDownLabel = uilabel(app.preprocessingAppUIFigure);
            app.PresetDropDownLabel.Text = 'Preset:';
            app.PresetDropDown = uidropdown(app.preprocessingAppUIFigure);
            app.PresetDropDown.ValueChangedFcn = createCallbackFcn(app, @PresetDropDownValueChanged, true);
            app.UserInitialsEditFieldLabel = uilabel(app.preprocessingAppUIFigure);
            app.UserInitialsEditFieldLabel.Text = 'User Initials:';
            app.UserInitialsEditField = uieditfield(app.preprocessingAppUIFigure, 'text');
            app.UserInitialsEditField.ValueChangedFcn = createCallbackFcn(app, @UserInitialsEditFieldValueChanged, true);
            app.StudyNameEditFieldLabel = uilabel(app.preprocessingAppUIFigure);
            app.StudyNameEditFieldLabel.Text = 'Study Name:';
            app.StudyNameEditField = uieditfield(app.preprocessingAppUIFigure, 'text');
            app.StudyNameEditField.ValueChangedFcn = createCallbackFcn(app, @StudyNameEditFieldValueChanged, true);
            app.NetTypeLabel = uilabel(app.preprocessingAppUIFigure);
            app.NetTypeLabel.Text = 'Net Type:';
            app.NetTypeDropDown = uidropdown(app.preprocessingAppUIFigure);
            app.NetTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @NetTypeDropDownValueChanged, true);
            app.FileFolderEditFieldLabel = uilabel(app.preprocessingAppUIFigure);
            app.FileFolderEditFieldLabel.Text = 'File Folder:';
            app.FileFolderEditField = uieditfield(app.preprocessingAppUIFigure, 'text');
            app.OutputFolderEditFieldLabel = uilabel(app.preprocessingAppUIFigure);
            app.OutputFolderEditFieldLabel.Text = 'Output Folder:';
            app.OutputFolderEditField = uieditfield(app.preprocessingAppUIFigure, 'text');
            app.OpenButton = uibutton(app.preprocessingAppUIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.UITable = uitable(app.preprocessingAppUIFigure);
            app.UITable.ColumnName = {'Column 1'};
            app.UITable.RowName = {};
            app.Stage1Button = uibutton(app.preprocessingAppUIFigure, 'push');
            app.Stage1Button.ButtonPushedFcn = createCallbackFcn(app, @Stage1ButtonPushed, true);
            app.Stage1Button.Text = 'Stage 1';
            app.Stage2Button = uibutton(app.preprocessingAppUIFigure, 'push');
            app.Stage2Button.ButtonPushedFcn = createCallbackFcn(app, @Stage2ButtonPushed, true);
            app.Stage2Button.Text = 'Stage 2';
            app.Stage3Button = uibutton(app.preprocessingAppUIFigure, 'push');
            app.Stage3Button.ButtonPushedFcn = createCallbackFcn(app, @Stage3ButtonPushed, true);
            app.Stage3Button.Text = 'Stage 3';
            app.Stage4Button = uibutton(app.preprocessingAppUIFigure, 'push');
            app.Stage4Button.ButtonPushedFcn = createCallbackFcn(app, @Stage4ButtonPushed, true);
            app.Stage4Button.Text = 'Stage 4';
            app.LastButton = uibutton(app.preprocessingAppUIFigure, 'push');
            app.LastButton.ButtonPushedFcn = createCallbackFcn(app, @LastButtonPushed, true);
            app.LastButton.Text = 'Last';
            app.Step1SetupPresetsandFileLocationsLabel = uilabel(app.preprocessingAppUIFigure);
            app.Step1SetupPresetsandFileLocationsLabel.Text = 'Step 1: Setup Presets and File Locations';
            app.ViewFolderButton = uibutton(app.preprocessingAppUIFigure, 'push');
            app.ViewFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ViewFolderButtonPushed, true);
            app.ViewFolderButton.Text = 'View Folder';
            app.PrepareFilesButton = uibutton(app.preprocessingAppUIFigure, 'push');
            app.PrepareFilesButton.ButtonPushedFcn = createCallbackFcn(app, @PrepareFilesButtonPushed, true);
            app.PrepareFilesButton.Text = 'Prepare Files';
            app.LoadStagesButton = uibutton(app.preprocessingAppUIFigure, 'push');
            app.LoadStagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStagesButtonPushed, true);
            app.LoadStagesButton.Text = 'Load Stages';
            app.PrepareMRAButton = uibutton(app.preprocessingAppUIFigure, 'push');
            app.PrepareMRAButton.ButtonPushedFcn = createCallbackFcn(app, @PrepareMRAButtonPushed, true);
            app.PrepareMRAButton.Text = 'Prepare MRA';
            app.AutoStage13Button = uibutton(app.preprocessingAppUIFigure, 'push');
            app.AutoStage13Button.ButtonPushedFcn = createCallbackFcn(app, @AutoStage13ButtonPushed, true);
            app.AutoStage13Button.Text = 'Auto Stage 1-3';
            app.Stage4AutoButton = uibutton(app.preprocessingAppUIFigure, 'push');
            app.Stage4AutoButton.ButtonPushedFcn = createCallbackFcn(app, @Stage4AutoButtonPushed, true);
            app.Stage4AutoButton.Text = 'Stage 4 Auto';
            app.ManualReviewButton = uibutton(app.preprocessingAppUIFigure, 'push');
            app.ManualReviewButton.ButtonPushedFcn = createCallbackFcn(app, @ManualReviewButtonPushed, true);
            app.ManualReviewButton.Text = 'Manual Review';
            app.CurrentCSVXXLabel = uilabel(app.preprocessingAppUIFigure);
            app.CurrentCSVXXLabel.Text = 'Current CSV: XX';
            app.SubjectLevelAnalysisPanel = uipanel(app.preprocessingAppUIFigure);
            app.SubjectLevelAnalysisPanel.Title = 'Subject-Level Analysis';
            app.LoadCSVButton = uibutton(app.SubjectLevelAnalysisPanel, 'push');
            app.LoadCSVButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCSVButtonPushed, true);
            app.LoadCSVButton.Text = 'Load CSV';
            app.GoButton = uibutton(app.SubjectLevelAnalysisPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.SubjectSelectionDropDownLabel = uilabel(app.SubjectLevelAnalysisPanel);
            app.SubjectSelectionDropDownLabel.Text = 'Subject Selection:';
            app.SubjectSelectionDropDown = uidropdown(app.SubjectLevelAnalysisPanel);
            app.QuickAnalysisDropDownLabel = uilabel(app.SubjectLevelAnalysisPanel);
            app.QuickAnalysisDropDownLabel.Text = 'Quick Analysis';
            app.QuickAnalysisDropDown = uidropdown(app.SubjectLevelAnalysisPanel);
            app.AvailableStagesDropDownLabel = uilabel(app.SubjectLevelAnalysisPanel);
            app.AvailableStagesDropDownLabel.Text = 'Available Stages:';
            app.AvailableStagesDropDown = uidropdown(app.SubjectLevelAnalysisPanel);
            app.AvailableStagesDropDown.ValueChangedFcn = createCallbackFcn(app, @AvailableStagesDropDownValueChanged, true);
            % Show the figure after all components are created
            app.preprocessingAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = htpPreprocessingGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.preprocessingAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.preprocessingAppUIFigure)
        end
    end
end